classdef TRABAJO_FINAL_MATLAB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        RaicesTextArea       matlab.ui.control.TextArea
        RaicesTextAreaLabel  matlab.ui.control.Label
        ymaxEditField        matlab.ui.control.NumericEditField
        ymaxEditFieldLabel   matlab.ui.control.Label
        yminEditField        matlab.ui.control.NumericEditField
        yminEditFieldLabel   matlab.ui.control.Label
        xmaxEditField        matlab.ui.control.NumericEditField
        xmaxEditFieldLabel   matlab.ui.control.Label
        xminEditField        matlab.ui.control.NumericEditField
        xminEditFieldLabel   matlab.ui.control.Label
        GRAFICARButton       matlab.ui.control.Button
        FxEditField          matlab.ui.control.EditField
        FxEditFieldLabel     matlab.ui.control.Label
        DIGITEELPOLINOMIOAGRAFICARLabel  matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GRAFICARButton
        function GRAFICARButtonPushed(app, event)
            V1 = app.FxEditField.Value ;

            V1str = str2sym(V1);

            V1sym = sym2poly(V1str);

            raices = roots(V1sym);
            app.RaicesTextArea.Value = string(raices);

            xmin = app.xminEditField.Value;
            xmax = app.xmaxEditField.Value;
            ymin = app.yminEditField.Value;
            ymax = app.ymaxEditField.Value;


            x = linspace(xmin, xmax, 100);
            y = polyval(V1sym, x);
            plot(app.UIAxes,x,y, 'G');


            if isinf(ymin)
                ymin = min(y);
            end
            if isinf (ymax)
                ymax = max(y);
            end
            app.UIAxes.XLim = [xmin, xmax];
            app.UIAxes.YLim = [ymin, ymax];


            app.UIAxes.YGrid = 'on';
            app.UIAxes.XGrid = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'F(x) - x')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'F(x)')
            zlabel(app.UIAxes, 'Z')
            app.DIGITEELPOLINOMIOAGRAFICARLabel = uilabel(app.UIFigure);
            app.DIGITEELPOLINOMIOAGRAFICARLabel.Text = 'DIGITE EL POLINOMIO A GRAFICAR: ';
            app.FxEditFieldLabel = uilabel(app.UIFigure);
            app.FxEditFieldLabel.Text = 'F(x) = ';
            app.FxEditField = uieditfield(app.UIFigure, 'text');
            app.FxEditField.Value = '(x+0.5)*(x-0.5)*(x-0.6)';
            app.GRAFICARButton = uibutton(app.UIFigure, 'push');
            app.GRAFICARButton.ButtonPushedFcn = createCallbackFcn(app, @GRAFICARButtonPushed, true);
            app.GRAFICARButton.Text = 'GRAFICAR';
            app.xminEditFieldLabel = uilabel(app.UIFigure);
            app.xminEditFieldLabel.Text = 'xmin';
            app.xminEditField = uieditfield(app.UIFigure, 'numeric');
            app.xminEditField.Value = -1;
            app.xmaxEditFieldLabel = uilabel(app.UIFigure);
            app.xmaxEditFieldLabel.Text = 'xmax';
            app.xmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.xmaxEditField.Value = 1;
            app.yminEditFieldLabel = uilabel(app.UIFigure);
            app.yminEditFieldLabel.Text = 'ymin';
            app.yminEditField = uieditfield(app.UIFigure, 'numeric');
            app.yminEditField.Value = -Inf;
            app.ymaxEditFieldLabel = uilabel(app.UIFigure);
            app.ymaxEditFieldLabel.Text = 'ymax';
            app.ymaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ymaxEditField.Value = Inf;
            app.RaicesTextAreaLabel = uilabel(app.UIFigure);
            app.RaicesTextAreaLabel.Text = 'Raices';
            app.RaicesTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TRABAJO_FINAL_MATLAB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end